classdef Flocking_App_modified < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        KEditFieldLabel                 matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        BetaEditFieldLabel              matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        sigmaEditFieldLabel             matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.NumericEditField
        LeaderTrajectoryLabel           matlab.ui.control.Label
        XtEditFieldLabel                matlab.ui.control.Label
        XtEditField                     matlab.ui.control.EditField
        YtEditFieldLabel                matlab.ui.control.Label
        YtEditField                     matlab.ui.control.EditField
        AgentsEditFieldLabel            matlab.ui.control.Label
        AgentsEditField                 matlab.ui.control.NumericEditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        NumberofIterationsEditField     matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        RunButton                       matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        CurrentIterationEditFieldLabel  matlab.ui.control.Label
        CurrentIterationEditField       matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel          matlab.ui.control.Label
        TimeStepEditField               matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        LeaderLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        agentData % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AgentsEditField
        function NumberOfAgentsUpdated(app, event)
            % This function adjusts the number of rows in the agent
            % position and velocity information so it refelcts the new
            % number of agents to be in the simulation.
            
            % New total number of agents 
            numNewAgents = app.AgentsEditField.Value; 
            
            % Old total number of agents
            numOldAgents = size(app.agentData,1);      
            
            % Updates the number of rows in the agent position and velocity
            % table to properly reflect the changes
            if numOldAgents < numNewAgents
                app.agentData = vertcat(app.agentData, zeros(numNewAgents-numOldAgents,4));
            else
                app.agentData = app.agentData(1:numNewAgents,:);
            end
            
            % Updates the Agent information data in the table
            app.UITable.Data = app.agentData;
            
        end

        % Button pushed function: RunButton
        function RunSimulation(app, event)
            % This function runs the simulation using the information given
            % by the user.
            
            % Initialize the iteration couter variable (current iteration field in app)
            app.CurrentIterationEditField.Value = 1;  
            % Number of iterations simulation is to run for
            numIterations = app.NumberofIterationsEditField.Value;
            
            % Reads the agent position and velcoity data and stores it to the global variable
            app.agentData = app.UITable.Data;
            % stores the x,y position data of each agent for a specified iteration
            agentPosition = app.agentData(:,1:2);     
            % stores the x,y velcoity data of each agent for a specified iteration 
            agentVelocity = app.agentData(:,3:4);               
            
            % Variables to store the position and velocity data for each agent throughout the simulation
            agentPositionHistory = zeros(numIterations, 2*app.AgentsEditField.Value);
            agentVelocityHistory = zeros(numIterations, 2*app.AgentsEditField.Value);
            agentPositionHistory(1,:) = reshape(agentPosition',1,[]);
            agentVelocityHistory(1,:) = reshape(agentVelocity',1,[]);
            
            % "Time step" between each iteration of the simulation
            timeStep = app.TimeStepEditField.Value;             
            % Setting the size of the square arena that the agents have to move in
            % arenaDimensions = 5;                              
            % set up symbolic variable used when interpreting trajectories
            syms t                                            

            % Reads in and converts input to a parametric equation for x component of leader trajectory
            leaderXPositionFunction = str2sym(app.XtEditField.Value);   
            % Reads in and converts input to a parametric equation for y component of leader trajectory
            leaderYPositionFunction = str2sym(app.YtEditField.Value); 
            % Differentiating the leaderXPositionFunction to get the X velocity of the leader
            leaderXVelocityFunction = diff(leaderXPositionFunction);
            % Differentiating the leaderYPositionFunction to get the Y velocity of the leader
            leaderYVeloictyFunction = diff(leaderYPositionFunction);
            
            % Initializing the leader's position and velocity based on the user defined trajectories
            leaderPosition = zeros(numIterations,2);
            leaderVelocity = zeros(numIterations,2);
            
            % Stores the position of the leader based on the user defined trajectory for each iteration of the simulation
            leaderPosition(1,:) = agentPosition(1,:);  
            % Stores the velocity of the leader based on the user defined trajectory for each iteration of the simulation
            leaderVelocity(1,:) = agentVelocity(1,:);
            
            
            % Populate X and Y position and velocity of leader for each iteration based on defined trajectory
            if ~strcmpi(app.XtEditField.Value,'') && ~strcmpi(app.YtEditField.Value,'')
                for iteration = 2:numIterations
                    t = (iteration-1)*timeStep;
                    leaderPosition(iteration,1) = subs(leaderXPositionFunction) + leaderPosition(1,1);
                    leaderPosition(iteration,2) = subs(leaderYPositionFunction) + leaderPosition(1,2);
                    leaderVelocity(iteration,1) = subs(leaderXVelocityFunction) + leaderVelocity(1,1);
                    leaderVelocity(iteration,2) = subs(leaderYVeloictyFunction) + leaderVelocity(1,2);
                end
            end
             
            while app.CurrentIterationEditField.Value < numIterations
                
                % STUDENT MADE FUNCTION: CalculateA -- used to calculate adjacency matrix 
                A = CalculateA(app.KEditField.Value, app.sigmaEditField.Value, app.BetaEditField.Value,agentPosition);
                % STUDENT MADE FUNCTION: CalculateL -- used to calculate Laplacian matrix
                L = CalculateL(A);
                
                % Updates the agents position
                agentPosition = agentPosition + (timeStep*agentVelocity);
                
                % Updates the agent position and history matrices
                agentPositionHistory(app.CurrentIterationEditField.Value,:) = reshape(agentPosition',1,[]);
                agentVelocityHistory(app.CurrentIterationEditField.Value,:) = reshape(agentVelocity',1,[]);
                
                % STUDENT MADE FUNCTION: Trigger -- to determine if trigger is activated for the current iteration
                if Trigger(app.CurrentIterationEditField.Value) == 1
                   % STUDENT MADE FUNCTION: VelocityUpdate -- updates agents' velocity if trigger activated
                   agentVelocity = VelocityUpdate(L,timeStep,agentVelocity);
                end
                
                % If user defined trajectory function exists, replace leader velocity update with that from the leaderVelocity
                % calculated previosly in the function
                if ~strcmpi(app.XtEditField.Value,'') && ~strcmpi(app.YtEditField.Value,'')
                    agentVelocity(1,:) = leaderVelocity(app.CurrentIterationEditField.Value,:);
                end
                
                app.UITable.Data = [agentPosition agentVelocity];
                
                % Increases iteration counter by 1
                app.CurrentIterationEditField.Value = app.CurrentIterationEditField.Value + 1;
                
                % ---------------------------------------------------------------------------------------------------
                
                % PLOTTING
                % First Plot previous up to current iteration position of agents. Current position is 
                % indicated with a 'O' marker and the previous points form its tail
                app.UIAxes.ColorOrderIndex = 1;
                cla(app.UIAxes)
                hold(app.UIAxes, 'on')
                plot(app.UIAxes,...
                    agentPositionHistory(1:app.CurrentIterationEditField.Value-1,1:2:end),...
                    agentPositionHistory(1:app.CurrentIterationEditField.Value-1,2:2:end),...
                    '-o',"MarkerIndices",app.CurrentIterationEditField.Value-1);    
                
                % Adding labels to each agent
                for agent = 1:app.AgentsEditField.Value
                    text(app.UIAxes,...
                    agentPositionHistory(app.CurrentIterationEditField.Value-1,2*agent-1) + 0.05,...
                    agentPositionHistory(app.CurrentIterationEditField.Value-1,2*agent),...
                    sprintf('%d',agent));
                end
                              
                hold(app.UIAxes, 'off')
                % Give time for plot to be shown on screen
                pause(0.05);
            end
            
            % ---------------------------------------------------------------------------------------------------
            
            % Writes Position History to an Excel Spreadsheet
            delete('SimulationPositionResults.csv');
            writematrix(agentPositionHistory,'SimulationPositionResults.csv');
            
            % Writes Velocity History to an Excel Spreadsheet
            delete('SimulationVelocityResults.csv');
            writematrix(agentVelocityHistory,'SimulationVelocityResults.csv');
                        
        end

        % Button pushed function: ResetButton
        function ResetSimulation(app, event)
            cla(app.UIAxes)
            app.UITable.Data = app.agentData;
        end

        % Button pushed function: StopButton
        function StopSimulation(app, event)
            app.CurrentIterationEditField.Value = app.NumberofIterationsEditField.Value+1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditField.Value = 1;
            app.BetaEditFieldLabel = uilabel(app.UIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.BetaEditField.Value = 0.25;
            app.sigmaEditFieldLabel = uilabel(app.UIFigure);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField.Value = 1;
            app.LeaderTrajectoryLabel = uilabel(app.UIFigure);
            app.LeaderTrajectoryLabel.Text = 'Leader Trajectory';
            app.XtEditFieldLabel = uilabel(app.UIFigure);
            app.XtEditFieldLabel.Text = 'X(t)';
            app.XtEditField = uieditfield(app.UIFigure, 'text');
            app.YtEditFieldLabel = uilabel(app.UIFigure);
            app.YtEditFieldLabel.Text = 'Y(t)';
            app.YtEditField = uieditfield(app.UIFigure, 'text');
            app.AgentsEditFieldLabel = uilabel(app.UIFigure);
            app.AgentsEditFieldLabel.Text = 'Agents';
            app.AgentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AgentsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfAgentsUpdated, true);
            app.NumberofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofIterationsEditField.Value = 20;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X Position'; 'Y Position'; 'X Velocity'; 'Y Velocity'};
            app.UITable.ColumnEditable = [true true true true];
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulation, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimulation, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSimulation, true);
            app.ResetButton.Text = 'Reset';
            app.CurrentIterationEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentIterationEditFieldLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentIterationEditField.Editable = 'off';
            app.TimeStepEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeStepEditField.Value = 0.2;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Agent Position')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LeaderLabel = uilabel(app.UIFigure);
            app.LeaderLabel.Text = 'Leader';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flocking_App_modified

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end