classdef Formation_App_modified < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        AllowOffsetCheckBox             matlab.ui.control.CheckBox
        PlotButton                      matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        MaxIterationEditFieldLabel      matlab.ui.control.Label
        MaxIterationEditField           matlab.ui.control.NumericEditField
        CurrentIterationEditFieldLabel  matlab.ui.control.Label
        CurrentIterationEditField       matlab.ui.control.NumericEditField
        LoadButton                      matlab.ui.control.Button
        AMatrixFileNameEditFieldLabel   matlab.ui.control.Label
        AMatrixFileNameEditField        matlab.ui.control.EditField
    end


    properties (Access = private)
        % Global variables pertaining to the A Matrices to be used for the simulation
        
        A_overall;          % A matrices used for simulation. 
                            % It is an n x n x z matrix where n = the number of agents and z = the number of A matrices, used for switching      
        num_matrices;       % Number of A matrices used in the simulation
        num_agents;         % Number of agents used in the simulation
    end


    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)


        end

        % Button pushed function: LoadButton
        function LoadButtonPushedFcn(app, event)
            % This function loads a saved A Matrix from a specified file name
            
            file = app.AMatrixFileNameEditField.Value; % Grabs given file name from user
            
            % If statement struture that checks is 1) the file name is valid (ie. a file of that name actually exists) and,
            %                                      2) the A Matrix is saved under the proper variabel name.
            if isfile(strcat(file,'.mat'))
                load(file,'AMatrix');
                if exist('AMatirx','var') == 0
                    app.A_overall = AMatrix;                % Storing A matrix to simulation global variable
                    app.num_matrices = size(app.A_overall,3);   % The number of switches that will be required in the program
                    app.num_agents = size(app.A_overall,1);     % The number of Agents to be used in the simulation
                    
                    for i = 1:size(app.A_overall,1)
                        app.UITable.Data(i,1) = i;
                    end 
                        
                    app.UITable.Data(:,2:6) = zeros(size(app.A_overall,1),5);
                    
                else
                    uialert(app.UIFigure,'Cannot read A Matrix from file. Please Change A matrix variable name to "AMatrix"','File Read Error');
                end
            else
                uialert(app.UIFigure,'File not found','Invalid File Name')
            end
            
        end

        % Value changed function: AllowOffsetCheckBox
        function OffsetValueChangedFcn(app, event)
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushedFcn(app, event)
            % Plots current iteration if pushed
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushedFcn(app, event)
            % When the play button is pressed ,the simulation is run for specified number of iterations.
            
            cla(app.UIAxes);    % Clearing plot axes
            
            % VARIABLES REQUIRED FOR FUNCTION
            table_data = app.UITable.Data(:,2:6);               % Stores x,y position, delay and x,y offset data for each agent
            position = zeros(2*app.num_agents,1);               % 2D matrix that stores x position (first num_agents rows) and 
                                                                % y position (second set of num_agents rows) of each agent from table_data
            
            tau = zeros(app.num_agents,1);                      % Array that stores the time delay assigned to each agent
            offset = zeros(2*app.num_agents,1);                 % This makes an offset vector of zeros.
            
            offset_check = app.AllowOffsetCheckBox.Value;       % Stores a 0 if offset check box is empty and a 1 if checked
            max_iteration = app.MaxIterationEditField.Value;    % Stores the desired numebr of iterations for the simulation
            t = 1;                                              % Time step variable used for plotting
            switch_array = [51];
            switch_index = 1;                                   % If switching A matrices during simulation, this variable keeps track of 
                                                                % which A matrix is to be used in the simulation.
            count = 1;
            diff = zeros(1,app.num_agents);
            
            % This finds the averages of the initial table data so that the consensus
            % values can be compared to the desired (average) values in the second plot
            % (i.e. app.UIAxes2)
            avg_x = mean(table_data(:,1));                      % Average x coordinate in first iteration
            avg_y = mean(table_data(:,2));                      % Average y coordinate in first iteration
            avg = [avg_x avg_y];                                % Average x,y coordinates in single pairing
           
            % ------------------------------------------------------------------------------------------------------------------------------------
            
            % PRE-PLOTTING LOOP CODE
            app.CurrentIterationEditField.Value = 1;            % Sets the current iteration value to 1            
            
            % This for loop fills the positon matrix with the x,y positions of each agent
            for i = 1:app.num_agents
                position(i) = table_data(i,1);
                position(i + app.num_agents) = table_data(i,2);
            end
            
            % This for loop stores the time delay, tau, for each agent
            for i = 1:app.num_agents
                tau(i) = table_data(i,3);
            end
            
            % This for loop stores the x and y offsets for each agent
            if offset_check == 1
                for i = 1:app.num_agents
                    offset(i) = table_data(i,4);
                    offset(i + app.num_agents) = table_data(i,5);
                end 
            end
            
            H = position;
            
            % ------------------------------------------------------------------------------------------------------------------------------------
            
            % CONSENSUS ALGORITHM
            % The reason that this loop accesses the current iteration value each time instead of using some counter variable is to allow
            % for the simulation to be stopped before its completion
            for i = 1:max_iteration
                % First Obtain A matrix depending on switch_index. Then calculate resulting D and L matrices. Each step is completed 
                % using the external functions created by the students.
                A = GetA(app.A_overall,switch_index);
                D = CalculateD(A);
                L_temp = CalculateL(A,D);
                
                % Calculate the time step, delta_t, required for the consensus algorithm to converge
                for s = 1:app.num_agents
                    d(s) = L_temp(s,s);
                end
                delta_t = 1 / (max(d) + 1);
                
                % Creates a 2*num_agents x 2*num_agents matrix. Refer to MATLAB documentation for kron use 
                L = kron(eye(2),L_temp);
                
                % The consensus algorithm acting on each pair of x,y coordinates
                for j = 1:app.num_agents
                    if tau(j) == 0
                        % This is the consensus algorithm for regular updating (no delays)
                        position(j) = H(j,t) - (delta_t * L(j,:) * (H(:,t) - offset));
                        position(j + app.num_agents) = H(j + app.num_agents,t) - (delta_t * L(j + app.num_agents,:) * (H(:,t) - offset));
                        
                    elseif t > tau(j) && mod(t, tau(j) + 1) == 0
                        % This is the consensus algorithm for delays in updating
                        position(j) = H(j,(t - tau(j))) - (delta_t * L_kron(j,:) * (H(:,t) - offset));
                        position(j + app.num_agents) = H(j + app.num_agents,(t - tau(j))) - (delta_t * L(j,:) * (H(:,t) - offset));
                        
                    else
                        % This is the case when we have a stubborn agent and tau(i) is set to Inf.
                        position(j) = position(j);
                    
                    end
                end
                
                t = t+1; % increment the time step by one
                
                app.CurrentIterationEditField.Value = app.CurrentIterationEditField.Value + 1; % Update the Current Iteration box in the GUI
                
                % This for loop updates the table_data matrix with the change in positions from the consensus algorithm
                for m = 1:app.num_agents
                    table_data(m,1) = position(m);
                    table_data(m,2) = position(m + app.num_agents);
                end
                
                app.UITable.Data(:, 2:3) = table_data(:,1:2); % Update the table display in the GUI
                
                %-------------------------------------------------------------------------------------------------------------------------------------------------
                
                % PLOTTING 
                app.UIAxes.ColorOrderIndex = 1;
                cla(app.UIAxes)
                hold(app.UIAxes,'on');
                for i = 1:app.num_agents
                    if table_data(i,3) == Inf
                        plot(app.UIAxes1, table_data(i,1),table_data(i,2),'Marker','o','MarkerEdgeColor','c','MarkerFaceColor','c');
                        x_axis_unit = (max(table_data(:,1)) -  ...
                            min(table_data(:,1))) / 14;
                        y_axis_unit = (max(table_data(:,2)) - ...
                            min(table_data(:,2))) / 14;
                        text(app.UIAxes, table_data(i,1) - x_axis_unit,table_data(i,2) ...
                            - y_axis_unit, sprintf('%d', i), 'color', 'k', ...
                            'fontweight', 'bold');
                    else
                    
                        plot(app.UIAxes, table_data(i,1),table_data(i,2),'Marker','o');
                        x_axis_unit = (max(table_data(:,1)) - ...
                            min(table_data(:,1))) / 14;
                        y_axis_unit = (max(table_data(:,2)) - ...
                            min(table_data(:,2))) / 14;
                        text(app.UIAxes, table_data(i,1) - x_axis_unit,table_data(i,2) ...
                            - y_axis_unit, sprintf('%d', i), 'color', 'k', ...
                            'fontweight', 'bold');
                    end
                    
                    app.UIAxes.XLim = ([min(table_data(:,1)) - 1, max(table_data(:,1)) + 1]);
                    app.UIAxes.YLim = ([min(table_data(:,2)) - 1, max(table_data(:,2)) + 1]);
                
                end
                
                app.UIAxes.ColorOrderIndex = 1;
                
                for j = 1:app.num_agents
                    for k = 1:app.num_agents
                        if A(j,k) == 1
                            line(app.UIAxes, [table_data(j,1),table_data(k,1)],[table_data(j,2),table_data(k,2)],'Color','k');
                        end
                    end
                end    
                hold(app.UIAxes,'off');
                app.UIAxes2.ColorOrderIndex = 1;
                
                for j = 1:app.num_agents
                    diff(count,j) = pdist([position(j) position(j + app.num_agents); avg]);
                end
              
                for i = 1:count
                    plot(app.UIAxes2, linspace(0, count, count), diff);
                    app.UIAxes2.XLim = [0 app.MaxIterationEditField.Value];
                end
                
                
                %--------------------------------------------------------------
            
                H = horzcat(H,position); % Horizontally concatenates the position resutls just calcualted to develop the history of the agents position

                % Increases count variable & puts a pause so graphing can be observed in the app
                count = count + 1;
                pause(0.2);
                
                % Checks if the A Matrix selection needs to be switched for the next iteration of the for loop
                if app.CurrentIterationEditField == switch_array(switch_index)
                    switch_index = switch_index + 1;
                end
                
            end


        end

        % Button pushed function: StopButton
        function StopButtonPushedFcn(app, event)
            app.CurrentIterationEditField.Value = app.MaxIterationEditField.Value + 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Agent'; 'X'; 'Y'; 'Delay'; 'X Offset'; 'Y Offset'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true true];
            app.AllowOffsetCheckBox = uicheckbox(app.UIFigure);
            app.AllowOffsetCheckBox.ValueChangedFcn = createCallbackFcn(app, @OffsetValueChangedFcn, true);
            app.AllowOffsetCheckBox.Text = 'Allow Offset';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushedFcn, true);
            app.PlotButton.Text = 'Plot';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushedFcn, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushedFcn, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.MaxIterationEditFieldLabel = uilabel(app.UIFigure);
            app.MaxIterationEditFieldLabel.Text = 'Max Iteration';
            app.MaxIterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentIterationEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentIterationEditFieldLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushedFcn, true);
            app.LoadButton.Text = 'Load';
            app.AMatrixFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.AMatrixFileNameEditFieldLabel.Text = 'A Matrix File Name';
            app.AMatrixFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.AMatrixFileNameEditField.Value = 'A-Matrix';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Formation_App_modified

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end