classdef Formation_App_modified_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileNameEditFieldLabel    matlab.ui.control.Label
        FileNameEditField         matlab.ui.control.EditField
        AMatricesLabel            matlab.ui.control.Label
        LoadButton                matlab.ui.control.Button
        AgentInformationLabel     matlab.ui.control.Label
        FileNameEditField_2Label  matlab.ui.control.Label
        FileNameEditField_2       matlab.ui.control.EditField
        LoadButton_2              matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        UITable                   matlab.ui.control.Table
        UITable2                  matlab.ui.control.Table
    end

    
    properties (Access = private)
        aOverall;
        agentSetUp;
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadAMatrix(app, event)
            file = app.FileNameEditField.Value;
           
            if isfile(strcat(file,'.mat'))
                load(file,'AMatrix');
                if exist('AMatirx','var') == 0
                    app.aOverall = AMatrix;      % Storing A Matrix to global variable
                                            
                    app.UITable.Data = zeros(size(app.aOverall,1),5);
                    
                    app.UITable2.Data = zeros(size(app.aOverall,3)-1,1);
                    
                else
                    uialert(app.UIFigure,'Cannot read A Matrix from file. Please change A matrix variable name to "AMatrix"','File Read Error');
                end
                
            else
                uialert(app.UIFigure,'File not found','Invalid File Name')
            end
            
        end

        % Button pushed function: LoadButton_2
        function LoadAgentInfo(app, event)
            file = app.FileNameEditField_2.Value;
           
            if isfile(strcat(file,'.mat'))
                load(file,'SetUp');
                if exist('SetUp','var') == 1
                    app.agentSetUp = SetUp;         % Storing Agent Set Up to global variable
                                            
                    app.UITable.Data = SetUp;
                    
                else
                    uialert(app.UIFigure,'Cannot read Set Up from file. Please change Agent Set Up to variable name to "SetUp"','File Read Error');
                end
                
            else
                uialert(app.UIFigure,'File not found','Invalid File Name')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Value = 'A-Matrix';
            app.AMatricesLabel = uilabel(app.UIFigure);
            app.AMatricesLabel.Text = 'A Matrices';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAMatrix, true);
            app.LoadButton.Text = 'Load';
            app.AgentInformationLabel = uilabel(app.UIFigure);
            app.AgentInformationLabel.Text = 'Agent Information';
            app.FileNameEditField_2Label = uilabel(app.UIFigure);
            app.FileNameEditField_2Label.Text = 'File Name';
            app.FileNameEditField_2 = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField_2.Value = 'Agent-Set-Up';
            app.LoadButton_2 = uibutton(app.UIFigure, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadAgentInfo, true);
            app.LoadButton_2.Text = 'Load';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Agent Position')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'; 'Delay'; 'X Offset'; 'Y Offset'};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Switch'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Formation_App_modified_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end