classdef MatrixEditorFormation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                          matlab.ui.Figure
        NumberofAgentsEditFieldLabel    matlab.ui.control.Label
        NumberofAgentsEditField         matlab.ui.control.NumericEditField
        ResetButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        NumberofMatricesEditFieldLabel  matlab.ui.control.Label
        NumberofMatricesEditField       matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        CurrentMatrixEditFieldLabel     matlab.ui.control.Label
        CurrentMatrixEditField          matlab.ui.control.NumericEditField
        PreviousButton                  matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
    end


    properties (Access = private)
        data; % Matrix entry data
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            % This function initializes the display for a Default A Matrix            
            app.data = zeros(4);
            app.UITable.Data = app.data;
        end

        % Value changed function: NumberofAgentsEditField
        function NumberOfAgents(app, event)
           % This function alters the number of columns and rows in the A matrix table to reflect the desired number of agents in the system.
           
           % New number of agents for system
           n = app.NumberofAgentsEditField.Value;
         
           % If Statements descern between cases of reducing number of agents or increasing the number of agents.
           if n < size(app.data,1)
               app.data = app.data(1:n,1:n,:);
           else
               % Number of agents being added to the matrices
               delta = n - size(app.data,1);
               
               % Increasing the number of rows in the matrix without altering the number of columns or number of matrices
               rowIncrease = zeros(delta,size(app.data,2),size(app.data,3));
               app.data = cat(1,app.data,rowIncrease);
               
               % Increasing the number of columns without altering the updated number of rows or the number of matrices
               colIncrease = zeros(size(app.data,1),delta,size(app.data,3));
               app.data = cat(2,app.data,colIncrease);
           end
           
           % Updating the table current visible in the GUI and the Overall Matrix Data for the system
           app.UITable.Data = app.data(:,:,app.CurrentMatrixEditField.Value);
           
        end

        % Cell edit callback: UITable
        function CellEditCallback(app, event)
            % This function saves any changes made to the A matrices made by the user
            indices = event.Indices;
            newData = event.NewData;
            currDepth = app.CurrentMatrixEditField.Value;
            app.data(indices(1),indices(2),currDepth) = newData;
            app.UITable.Data = app.data(:,:,currDepth);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushedFcn(app, event)
            % This function resets the parameters to their original settings when the program opened
            app.NumberofMatricesEditField.Value = 1;
            app.CurrentMatrixEditField.Value = 1;
            app.data = zeros(4);
            app.UITable.Data = app.data;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushedFcn(app, event)
            % Saves the current A Matrices into an .mat file of specifed name
            matrix = app.data;
            save(app.FileNameEditField.Value,'matrix');
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushedFcn(app, event)
            % This function allows the user to access the previous matrix in the series of A matrices
            % Current Matrix Number
            currentMatrix = app.CurrentMatrixEditField.Value - 1;
            % wraps previous to max value if next matrix index would otherwise be 0.
            if currentMatrix == 0
                currentMatrix = size(app.data,3);
            end
            % Updating the current matrix indicator
            app.CurrentMatrixEditField.Value = currentMatrix;
            % Calling the current matrix from the Overall Data and displaying it in the GUI
            app.UITable.Data = app.data(:,:,currentMatrix);
        end

        % Button pushed function: NextButton
        function NextButtonPushedFcn(app, event)
            % This function allows for the user to access the next matrix in the series of A matrices
            % Current Matrix Number
            currentMatrix = app.CurrentMatrixEditField.Value + 1;
            % wraps previous to max value if next matrix number would otherwise be outside the number of matrix range.
            if currentMatrix > size(app.data,3)
                currentMatrix = 1;
            end
            % Updating the current matrix indicator
            app.CurrentMatrixEditField.Value = currentMatrix;
            % Update table data
            app.UITable.Data = app.data(:,:,currentMatrix);
        end

        % Value changed function: NumberofMatricesEditField
        function NumMatricesValueChangedFcn(app, event)
            % This function updates the number of A Matrices that are to be created
            % Matrix depth after
            sizeNew = app.NumberofMatricesEditField.Value;
            % Matrix depth before
            sizeOld = size(app.data,3);
            nRows = size(app.data,1);
            % Increase depth
            if sizeOld < sizeNew
                app.data = cat(3,app.data,zeros(nRows,nRows,sizeNew-sizeOld));
            else
                app.data = app.data(:,:,1:sizeNew);
            end
            % Ensure table displays valid data
            if app.CurrentMatrixEditField.Value > sizeNew
                app.CurrentMatrixEditField.Value = sizeNew;
                app.UITable.Data = app.data(:,:,sizeNew);
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushedFcn(app, event)
            % This function recalls saved A Matrix data and sets it up within the GUI
            filename = app.FileNameEditField.Value;
            if isfile(filename)
                file = load(filename);
                try
                    app.data = file.matrix;
                    app.NumberofMatricesEditField.Value = size(app.data,3);
                    app.NumberofAgentsEditField.Value = size(app.data,1);
                    app.CurrentMatrixEditField.Value = 1;
                    app.UITable.Data = app.data(:,:,1);
                catch ME
                    warning('Bad matrix file: No matrix variable in file')
                end
            else
                warning('Bad matrix file: file not found')
            end
            
        end

        % Close request function: Window
        function CloseRequestFcn(app, event)
            % This function acts as a failsafe to prevent the user from accidentaly closing the program before saving their work
            
            msg = 'Are you sure you want to close?';
            title = 'Close';
            selection = uiconfirm(app.Window,msg,title,...
                'Options',{'Exit','Save & Exit','Cancel'},...
                'DefaultOption',2,'CancelOption',3);
            switch selection
                case 'Exit'
                    delete(app)
                case 'Save & Exit'
                    matrix = app.data;
                    save(app.FileNameEditField.Value,'matrix');
                    delete(app);
                case 'Cancel'
                    % Return to app
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Matrix Editor (Formation)';
            app.Window.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.NumberofAgentsEditFieldLabel = uilabel(app.Window);
            app.NumberofAgentsEditFieldLabel.Text = 'Number of Agents';
            app.NumberofAgentsEditField = uieditfield(app.Window, 'numeric');
            app.NumberofAgentsEditField.Limits = [0 Inf];
            app.NumberofAgentsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofAgentsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfAgents, true);
            app.NumberofAgentsEditField.Value = 4;
            app.ResetButton = uibutton(app.Window, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushedFcn, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.Window, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushedFcn, true);
            app.SaveButton.Text = 'Save';
            app.NumberofMatricesEditFieldLabel = uilabel(app.Window);
            app.NumberofMatricesEditFieldLabel.Text = 'Number of Matrices';
            app.NumberofMatricesEditField = uieditfield(app.Window, 'numeric');
            app.NumberofMatricesEditField.Limits = [1 Inf];
            app.NumberofMatricesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofMatricesEditField.ValueChangedFcn = createCallbackFcn(app, @NumMatricesValueChangedFcn, true);
            app.NumberofMatricesEditField.Value = 1;
            app.UITable = uitable(app.Window);
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @CellEditCallback, true);
            app.CurrentMatrixEditFieldLabel = uilabel(app.Window);
            app.CurrentMatrixEditFieldLabel.Text = 'Current Matrix';
            app.CurrentMatrixEditField = uieditfield(app.Window, 'numeric');
            app.CurrentMatrixEditField.Limits = [1 Inf];
            app.CurrentMatrixEditField.ValueDisplayFormat = '%.0f';
            app.CurrentMatrixEditField.Editable = 'off';
            app.CurrentMatrixEditField.Value = 1;
            app.PreviousButton = uibutton(app.Window, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushedFcn, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.Window, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushedFcn, true);
            app.NextButton.Text = 'Next';
            app.LoadButton = uibutton(app.Window, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushedFcn, true);
            app.LoadButton.Text = 'Load';
            app.FileNameEditFieldLabel = uilabel(app.Window);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.Window, 'text');
            app.FileNameEditField.Value = 'A.mat';
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatrixEditorFormation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end