classdef Lloyd_App_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                          matlab.ui.Figure
        ArenaPlot                       matlab.ui.control.UIAxes
        CoveragePlot                    matlab.ui.control.UIAxes
        PointPlot                       matlab.ui.control.UIAxes
        CommPlot                        matlab.ui.control.UIAxes
        NumberofAgentsEditFieldLabel    matlab.ui.control.Label
        NumAgentsEditField              matlab.ui.control.NumericEditField
        SideLengthEditFieldLabel        matlab.ui.control.Label
        SideLengthEditField             matlab.ui.control.NumericEditField
        PartitionNumberEditFieldLabel   matlab.ui.control.Label
        PartitionNumberEditField        matlab.ui.control.NumericEditField
        RadiusofObservationEditFieldLabel  matlab.ui.control.Label
        RadiusofObservationEditField    matlab.ui.control.NumericEditField
        RadiusofCommunicationEditFieldLabel  matlab.ui.control.Label
        RadiusofCommunicationEditField  matlab.ui.control.NumericEditField
        VelocityDropDownLabel           matlab.ui.control.Label
        VelocityDropDown                matlab.ui.control.DropDown
        VelocityEditField               matlab.ui.control.NumericEditField
        DelayEditFieldLabel             matlab.ui.control.Label
        DelayEditField                  matlab.ui.control.NumericEditField
        SimulationDurationEditFieldLabel  matlab.ui.control.Label
        SimulationDurationEditField     matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        PositionTable                   matlab.ui.control.Table
        CurrentIterationEditFieldLabel  matlab.ui.control.Label
        CurrentIterationEditField       matlab.ui.control.NumericEditField
        DensityMatrixNameEditFieldLabel  matlab.ui.control.Label
        DensityMatrixNameEditField      matlab.ui.control.EditField
        SimulationControlsLabel         matlab.ui.control.Label
        DensityLabel                    matlab.ui.control.Label
        VelocityFieldLabel              matlab.ui.control.Label
        VelocityLabel                   matlab.ui.control.Label
        AgentsLabel                     matlab.ui.control.Label
        ArenaLabel                      matlab.ui.control.Label
        ScaleFactorEditField            matlab.ui.control.NumericEditField
        ScaleFactorLabel                matlab.ui.control.Label
        InitialPositionFileEditFieldLabel  matlab.ui.control.Label
        InitialPositionFileEditField    matlab.ui.control.EditField
        LoadPositionsButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        isStopPushed % Holds state of stop button
    end

    methods (Access = private)
        function plotCommunication(app,adjMatrix,agentPositions)
            % Clear old communication plot
            cla(app.CommPlot);
            % Get plot values (can't do gplot directly to specified axis in 2019a)
            [X,Y] = gplot(adjMatrix, agentPositions);
            % Plot values
            plot(app.CommPlot, X, Y,'-or','LineWidth',1,'MarkerFaceColor','auto');
        end
        
        function plotCoverageDistance(app,coverage,distanceTravelled,i)
            % Clear old coverage/distance plot
            cla(app.CoveragePlot);
            % Switch to left axis
            yyaxis(app.CoveragePlot, 'left');
            cla(app.CoveragePlot);
            % Hold to plot both y axes
            hold(app.CoveragePlot,'on');
            plot(app.CoveragePlot, 1:i, coverage(1:i),'Marker','none','Color','b');
            % Plot distance travelled on the right
            yyaxis(app.CoveragePlot, 'right');
            ylabel(app.CoveragePlot, 'Distance Travelled');
            plot(app.CoveragePlot, 1:i, distanceTravelled(1:i),'Marker','none','Color','r');
            hold(app.CoveragePlot,'off');
        end
        
        function plotArena(app,agentPositions,centroids,density,xMesh,yMesh,sides)
            % Offset of agent labels
            delta = sides/65;
            numAgents = size(agentPositions,1);
            
            % Clear old arena plot
            cla(app.ArenaPlot);
            % Hold to draw all these plots
            hold(app.ArenaPlot, 'on');
            % Plot agents with blue dots
            scatter(app.ArenaPlot, agentPositions(:,1), agentPositions(:,2), 90,'blue','filled');
            % Add agent labels
            text(app.ArenaPlot, agentPositions(:,1)+delta, agentPositions(:,2)+delta,...
                cellfun(@num2str,num2cell((1:numAgents)'),'UniformOutput',false));
            % Plot centroids with red dots
            scatter(app.ArenaPlot,centroids(:,1),centroids(:,2),50,'red','filled');
            % Voronoi diagram
            voronoi(app.ArenaPlot, agentPositions(:,1),agentPositions(:,2));
            % Contour plot of density
            [~, contPlot] = contour(app.ArenaPlot, xMesh, yMesh, density, 4);
            % Change line width on contour plot
            set(contPlot,'LineWidth',0.5)
            hold(app.ArenaPlot,'off');
        end
        
        function saveSimulationData(~,agentPositionHistory, coverage, distanceTravelled, densityHistory)
            ssName = 'agentData.xls';
            matName = 'agentData.mat';
            if isfile(ssName)
                delete(ssName);
            end
            if isfile(matName)
                delete(matName);
            end
            save(matName,'agentPositionHistory','coverage','distanceTravelled','densityHistory');
            writematrix(agentPositionHistory,ssName,'FileType','spreadsheet','Sheet','Agent Position History');
            writematrix(coverage,ssName,'FileType','spreadsheet','Sheet','Coverage');
            writematrix(distanceTravelled,ssName,'FileType','spreadsheet','Sheet','Distance Travelled');
            writematrix(densityHistory,ssName,'FileType','spreadsheet','Sheet','Density History');
        end
        
        function D = loadDensity(~, densityFileName, expectedSize)
            if isfile(densityFileName)
                try
                    densityFile = load(densityFileName);
                    syms D(t);
                    % Try to optimize load time by avoiding str2sym
                    if densityFile.isDensity
                        D = densityFile.matrix;
                    else
                        D = str2sym(densityFile.matrix);
                    end
                    % Ensure density function is in terms of only t
                    if ~(isequal(symvar(D), t) || isempty(symvar(D)))
                        error('Bad density matrix: The entries of your density matrix are invalid')
                    end
                catch ME
                    error(['Bad density matrix: ' densityFileName ' does not contain the variable ''matrix''. ' ...
                        'Ensure you are using the Matrix Editor app to create a density matrix'])
                end
            else
                error('Bad density matrix: File does not exist')
            end
            if size(D) ~= expectedSize*ones(1,2)
                error('Bad density matrix: Dimensions of density matrix must equal sides*partitions')
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Generates random positions upon startup
        numAgents = app.NumAgentsEditField.Value;
        sides = app.SideLengthEditField.Value;
        app.PositionTable.Data = rand(numAgents,2)*sides;
        end

        % Button pushed function: StartButton
        function startButtonPushed(app, event)
%% MAIN PROGRAM LOOP %%
%% TODO: Assigned points plot
%% TODO: Better contour plot
%% TODO: Plot circles for observation/communication?
%% TODO: Play around with proportional velocity
%% TODO: Energy/energy plot
%% TODO: Fix distance travelled plot (capped at 100)
%% TODO: Density history skips an iteration
close('all');
app.isStopPushed = false;

%% Get UI Values %%
sides = app.SideLengthEditField.Value;
partitions = app.PartitionNumberEditField.Value;
agentPositions = app.PositionTable.Data;
numAgents = app.NumAgentsEditField.Value;
maxVelocity = app.VelocityEditField.Value;
velocityScaleFactor = app.ScaleFactorEditField.Value;
velocityType = app.VelocityDropDown.Value;
duration = app.SimulationDurationEditField.Value;
rObs = app.RadiusofObservationEditField.Value;
rComm = app.RadiusofCommunicationEditField.Value;
delay = app.DelayEditField.Value;
%% END Get UI Values %%

%% Initialize plots %%
set(app.CommPlot,'XLim',[0 sides],'YLim',[0 sides]);
set(app.ArenaPlot,'XLim',[0 sides],'YLim',[0 sides]);
set(app.CoveragePlot,'YLim',[0 100]);
% Need meshgrid to impose density over grid with physical dimension
% sides-by-sides
[xMesh, yMesh] = meshgrid(1:sides*partitions);
xMesh = xMesh/partitions;
yMesh = yMesh/partitions;
yyaxis(app.CoveragePlot, 'right');
ylabel(app.CoveragePlot, 'Distance Travelled');
%% END Initialize Plots %%

%% Load density matrix %%
syms D(t);
densityFileName = app.DensityMatrixNameEditField.Value;
D = loadDensity(app, densityFileName, partitions*sides);
%% END Load density matrix %%

%% Agent Positions setup %%
agentPositions = agentPositionFix(agentPositions,partitions,sides);
%% END Agent Positions setup %%

%% Dynamic var setup %%
iteration = 1;
% Turn symbolic D to currentDensity matrix
currentDensity = double(subs(D,t,iteration));
totalMass = sum(currentDensity,'all');
%% END Dynamic var setup %%

%% Stats setup %%
% Allocate all agentPositionHistory
% Each row is the positions of each agent in [x1 y1 x2 y2 ...] format
agentPositionHistory = zeros(duration, numAgents*2);
agentPositionHistory(1,:) = reshape(agentPositions', [1 numAgents*2]);

% coverage is a column vector of percent of total arena observed during each iteration
coverage = zeros(duration,1);

% distanceTravelled is a column vector of the total distance travelled by all agents
% in each iteration
distanceTravelled = zeros(duration,1);

% Keep track of density over time
densityHistory = zeros(sides*partitions*duration, sides*partitions);
densityHistory(1:sides*partitions,:) = currentDensity;
%% END Stats setup %%

%% Energy %%
E = 100*ones(1,numAgents);
%% END Energy %%

%----------------------------------------- Loop -----------------------------------------------------%
while (iteration <= duration) && ~app.isStopPushed
    % Update Current Iteration field
    app.CurrentIterationEditField.Value = iteration;
    
    %% Calculate Communication %%
    % Calculate agent communication cells and adjacency matrix
    [commCells, adjMatrix] = communication(agentPositions,rComm);
    plotCommunication(app, adjMatrix, agentPositions);
    %% END Calculate Communication %%
    
    %% Points, Mass, and Coverage Calculation %%
    % Assign points to agents to generate their observed region
    agentPoints = assignAgentPoints(agentPositions, commCells, sides,...
        partitions, rObs);
    % Calculate mass of each observed region
    mass = calcMass(agentPoints, currentDensity, partitions);
    coverage(iteration) = 100*calcCoverage(agentPoints, sides, partitions);
    %% END Points, Mass, and Coverage Calculation %%
    
    %% Centroids and Movement %%
    % Calculate centroids of each region for agents to move towards
    centroids = calcCentroids(agentPoints, mass, currentDensity, agentPositions,...
        partitions);
    % Move the agents
    [agentPositions,distanceChange,E] = moveAgents(agentPositions,...
        centroids, E, velocityType, maxVelocity, velocityScaleFactor);
    agentPositionHistory(iteration,:) = reshape(agentPositions', [1 numAgents*2]);
    if iteration == 1
        distanceTravelled(1,1) = distanceChange;
    else
        distanceTravelled(iteration,1) = distanceTravelled(iteration-1,1) + distanceChange;
    end
    %% END Centroids and Movement %%
    
    %% Plots %%
    % Update table data
    app.PositionTable.Data(:,1:2) = agentPositions;
    plotCoverageDistance(app, coverage, distanceTravelled, iteration);
    plotArena(app,agentPositions,centroids,currentDensity,xMesh,yMesh,sides);
    %% END Plots %%
    
    iteration = iteration + 1;
    
    %% Density Variation %%
    if iteration <= duration
        % Check if we update density this iteration
        if mod(iteration,delay) == 0
            % Always cast symbolic to double for faster computation in loop
            currentDensity = double(subs(D,t,iteration));
            totalMass = sum(currentDensity,'all');
        end
        densityHistory(iteration*sides*partitions+1:(iteration+1)*sides*partitions,:) = currentDensity;
    end
    %% END Density Variation
    pause(0.2);

end
%% END OF LOOP

% Save data and stuff
close('all')
saveSimulationData(app, agentPositionHistory, coverage, distanceTravelled, densityHistory);

%%%%%%%%%%%%%% OLD STUFF %%%
%% Plot Agent Positions %%
% theta = linspace(0,2*pi,100);
% x = rObs.*cos(theta) + agentPositions(i,1);
% y = rObs.*sin(theta) + agentPositions(i,2);
% 
% plot(app.ArenaPlot,x,y,'magenta')
% Plot radius of observation around agents
% circle_Maker(app, agentPositions(i,1), agentPositions(i,2),rObs); 
% Contour plot of density
% hold(app.ArenaPlot, 'on')
% [contour_plot, h] = contour(app.ArenaPlot,xx,yy,currentDensity,4); % h is used for line width
% set(h,'linewidth',2);
% str = app.NameofCostFunction2EditField.Value;
% stra = 'Coverage and ';
% str = [stra str];
% independent = 1:iteration; % for plotting cost function 2 (distance travelled)
% yyaxis(app.CoveragePlot, 'right') 
% ylabel(app.CoveragePlot, app.CostFunction2YAxisEditField.Value);
% title(app.CoveragePlot, str)
% plot(app.CoveragePlot,independent, distanceTravelled, 'r');
% hold(app.CoveragePlot, 'off')
% hold(app.PointPlot, 'on')
% Plot_Assigned_Points(app, agentPoints,numAgents,colours_energy,...
%     agentPositions,sides);
% hold(app.PointPlot, 'on')
% pause(0.1);
%% END Plot Others %%
%%%%%%%%%%%%%
        end

        % Button pushed function: StopButton
        function stopButtonPushed(app, event)
        % Updates state of isStopPushed to end simulation
        app.CurrentIterationEditField.Value = 0;
        app.isStopPushed = true;
        end

        % Value changed function: NumAgentsEditField
        function numAgentsChanged(app, event)
            % Adds/removes agents upon numAgents changing
            numAgents = app.NumAgentsEditField.Value;
            oldNumAgents = size(app.PositionTable.Data,1);
            delta = numAgents - oldNumAgents;
            sides = app.SideLengthEditField.Value;
            % If adding agents
            if delta > 0
                app.PositionTable.Data = vertcat(app.PositionTable.Data, rand(delta,2)*sides);
            % If removing agents
            else
                app.PositionTable.Data = app.PositionTable.Data(1:numAgents,:);
            end
        end

        % Value changed function: SideLengthEditField
        function sidesChanged(app, event)
            % Filter any invalid positions via logical indexing
            sides = app.SideLengthEditField.Value;
            positions = app.PositionTable.Data;
            positions(positions>sides) = sides;
            app.PositionTable.Data = positions;
        end

        % Value changed function: VelocityDropDown
        function velocityTypeChanged(app, event)
            value = app.VelocityDropDown.Value;
            % The Velocity edit field acts in different ways depending on
            % Velocity type, and the label reflects that
            if strcmp(value, 'Constant Velocity')
                app.VelocityFieldLabel.Text = 'Velocity';
                set(app.ScaleFactorEditField,'Enable',false,'Editable',false,'Value',1);
                set(app.ScaleFactorLabel,'Enable',false);
            else
                app.VelocityFieldLabel.Text = 'Max Velocity';
                set(app.ScaleFactorEditField,'Enable',true,'Editable',true);
                set(app.ScaleFactorLabel,'Enable',true);
            end
        end

        % Button pushed function: LoadPositionsButton
        function loadPositions(app, event)
            % Loads initial positions from the file specified
            % Stores them in the position table
            positionFileName = app.InitialPositionFileEditField.Value;
            numAgents = app.NumAgentsEditField.Value;
            sides = app.SideLengthEditField.Value;
            if isfile(positionFileName)
                try
                    positionFile = load(positionFileName);
                    positions = str2double(positionFile.matrix);
                    % Ensure there are enough rows in position matrix
                    if numAgents > size(positions,1)
                        error(['Bad position matrix: Not enough rows for ' numAgents ' agents'])
                    % Ensure there are x,y coordinates for all agents
                    elseif size(positions,2) ~= 2
                        error('Bad position matrix: Matrix must have 2 columns in x, y format')
                    % Ensure no NaN values in positions
                    elseif sum(sum(isnan(positions)))
                        error('Bad position matrix: NaN values not allowed')
                    else
                        % Filter all invalid positions via logical indexing
                        positions(positions<0) = 0;
                        positions(positions>sides) = sides;
                        app.PositionTable.Data = positions;
                    end
                catch ME
                    disp(ME)
                    error(['Bad position matrix: ' positionFileName ' does not contain the variable ''matrix''. ' ...
                        'Ensure you are using the Matrix Editor app to create a position matrix'])
                end
            else
                error('Bad position matrix: File does not exist')
            end
        end

        % Cell edit callback: PositionTable
        function positionChanged(app, event)
            indices = event.Indices;
            newData = event.NewData;
            sides = app.SideLengthEditField.Value;
            % Filter new value
            if newData > sides
                newData = sides;
            elseif newData < 0 || isnan(newData)
                newData = 0;
            end
            app.PositionTable.Data(indices(1),indices(2)) = newData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Lloyd''s Deployment Algorithm';
            app.ArenaPlot = uiaxes(app.Window);
            title(app.ArenaPlot, 'Arena')
            xlabel(app.ArenaPlot, 'X')
            ylabel(app.ArenaPlot, 'Y')
            app.CoveragePlot = uiaxes(app.Window);
            title(app.CoveragePlot, 'Coverage and Distance')
            xlabel(app.CoveragePlot, 'Iteration Number')
            ylabel(app.CoveragePlot, 'Percent Covered')
            app.PointPlot = uiaxes(app.Window);
            title(app.PointPlot, 'Assigned Points')
            app.CommPlot = uiaxes(app.Window);
            title(app.CommPlot, 'Communication Graph')
            xlabel(app.CommPlot, 'X')
            ylabel(app.CommPlot, 'Y')
            app.NumberofAgentsEditFieldLabel = uilabel(app.Window);
            app.NumberofAgentsEditFieldLabel.Text = 'Number of Agents';
            app.NumAgentsEditField = uieditfield(app.Window, 'numeric');
            app.NumAgentsEditField.ValueChangedFcn = createCallbackFcn(app, @numAgentsChanged, true);
            app.NumAgentsEditField.Value = 10;
            app.SideLengthEditFieldLabel = uilabel(app.Window);
            app.SideLengthEditFieldLabel.Text = 'Side Length';
            app.SideLengthEditField = uieditfield(app.Window, 'numeric');
            app.SideLengthEditField.ValueChangedFcn = createCallbackFcn(app, @sidesChanged, true);
            app.SideLengthEditField.Value = 5;
            app.PartitionNumberEditFieldLabel = uilabel(app.Window);
            app.PartitionNumberEditFieldLabel.Text = 'Partition Number';
            app.PartitionNumberEditField = uieditfield(app.Window, 'numeric');
            app.PartitionNumberEditField.Value = 10;
            app.RadiusofObservationEditFieldLabel = uilabel(app.Window);
            app.RadiusofObservationEditFieldLabel.Text = 'Radius of Observation';
            app.RadiusofObservationEditField = uieditfield(app.Window, 'numeric');
            app.RadiusofObservationEditField.Value = 2;
            app.RadiusofCommunicationEditFieldLabel = uilabel(app.Window);
            app.RadiusofCommunicationEditFieldLabel.Text = 'Radius of Communication';
            app.RadiusofCommunicationEditField = uieditfield(app.Window, 'numeric');
            app.RadiusofCommunicationEditField.Value = 3;
            app.VelocityDropDownLabel = uilabel(app.Window);
            app.VelocityDropDownLabel.Text = 'Velocity';
            app.VelocityDropDown = uidropdown(app.Window);
            app.VelocityDropDown.Items = {'Constant Velocity', 'Proportional Velocity'};
            app.VelocityDropDown.ValueChangedFcn = createCallbackFcn(app, @velocityTypeChanged, true);
            app.VelocityDropDown.Value = 'Constant Velocity';
            app.VelocityEditField = uieditfield(app.Window, 'numeric');
            app.VelocityEditField.Value = 1;
            app.DelayEditFieldLabel = uilabel(app.Window);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.Window, 'numeric');
            app.DelayEditField.Value = 5;
            app.SimulationDurationEditFieldLabel = uilabel(app.Window);
            app.SimulationDurationEditFieldLabel.Text = 'Simulation Duration';
            app.SimulationDurationEditField = uieditfield(app.Window, 'numeric');
            app.SimulationDurationEditField.Value = 50;
            app.StartButton = uibutton(app.Window, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Window, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PositionTable = uitable(app.Window);
            app.PositionTable.ColumnName = {'X'; 'Y'};
            app.PositionTable.ColumnEditable = [true true];
            app.PositionTable.CellEditCallback = createCallbackFcn(app, @positionChanged, true);
            app.CurrentIterationEditFieldLabel = uilabel(app.Window);
            app.CurrentIterationEditFieldLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.Window, 'numeric');
            app.CurrentIterationEditField.Editable = 'off';
            app.CurrentIterationEditField.Value = 1;
            app.DensityMatrixNameEditFieldLabel = uilabel(app.Window);
            app.DensityMatrixNameEditFieldLabel.Text = 'Density Matrix Name';
            app.DensityMatrixNameEditField = uieditfield(app.Window, 'text');
            app.DensityMatrixNameEditField.Value = 'density.mat';
            app.SimulationControlsLabel = uilabel(app.Window);
            app.SimulationControlsLabel.Text = 'Simulation Controls';
            app.DensityLabel = uilabel(app.Window);
            app.DensityLabel.Text = 'Density';
            app.VelocityFieldLabel = uilabel(app.Window);
            app.VelocityFieldLabel.Text = 'Velocity';
            app.VelocityLabel = uilabel(app.Window);
            app.VelocityLabel.Text = 'Velocity';
            app.AgentsLabel = uilabel(app.Window);
            app.AgentsLabel.Text = 'Agents';
            app.ArenaLabel = uilabel(app.Window);
            app.ArenaLabel.Text = 'Arena';
            app.ScaleFactorEditField = uieditfield(app.Window, 'numeric');
            app.ScaleFactorEditField.Editable = 'off';
            app.ScaleFactorEditField.Enable = 'off';
            app.ScaleFactorEditField.Value = 1;
            app.ScaleFactorLabel = uilabel(app.Window);
            app.ScaleFactorLabel.Enable = 'off';
            app.ScaleFactorLabel.Text = 'Scale Factor';
            app.InitialPositionFileEditFieldLabel = uilabel(app.Window);
            app.InitialPositionFileEditFieldLabel.Text = 'Initial Position File';
            app.InitialPositionFileEditField = uieditfield(app.Window, 'text');
            app.InitialPositionFileEditField.Value = 'positions.mat';
            app.LoadPositionsButton = uibutton(app.Window, 'push');
            app.LoadPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @loadPositions, true);
            app.LoadPositionsButton.Text = 'Load Positions';
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lloyd_App_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end