classdef OpinionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                     matlab.ui.Figure
        NodeDataTable              matlab.ui.control.Table
        StartButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        NumberofNodesLabel         matlab.ui.control.Label
        NodesEditField             matlab.ui.control.NumericEditField
        DimensionsButtonGroup      matlab.ui.container.ButtonGroup
        One                        matlab.ui.control.RadioButton
        Two                        matlab.ui.control.RadioButton
        NodeTrajectoryPlot         matlab.ui.control.UIAxes
        SimulationDurationLabel    matlab.ui.control.Label
        DurationEditField          matlab.ui.control.NumericEditField
        CurrentIterationLabel      matlab.ui.control.Label
        CurrentIterationEditField  matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel     matlab.ui.control.Label
        TimeStepEditField          matlab.ui.control.NumericEditField
        SimulationControlsLabel    matlab.ui.control.Label
        LoadButton                 matlab.ui.control.Button
        AgentInformationLabel      matlab.ui.control.Label
        FilenameEditFieldLabel     matlab.ui.control.Label
        FilenameEditField          matlab.ui.control.EditField
        NodeLocationPlot           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        isStopPushed;
    end
    
    methods (Access = private)
        
        function saveSimulationData(~,nodeDataHistory, f)
            ssName = 'nodeData.xlsx';
            matName = 'nodeData.mat';
            if isfile(ssName)
                delete(ssName)
            end
            if isfile(matName)
                delete(matName);
            end
            % Trim history to final iteration
            nodeData = nodeDataHistory(1:f,:);
      
            save(matName,'nodeData');
            writematrix(nodeData,ssName,'FileType','spreadsheet','Sheet','Agent Position History');
       end
        
        function toggleDimensionUI(app, isOneDimensional)
            if isOneDimensional
                % Change column headers
                set(app.NodeDataTable,'ColumnName',{'Radius of Comm.','Position',...
                    'Left Noise','Right Noise'}');
                app.One.Value = true;
                app.Two.Value = false;
                % Show trajectory plot
                set(app.NodeTrajectoryPlot,'Visible',true);
                % Decrease location plot size
                set(app.NodeLocationPlot,'Position',[12 208 300 185]);
            else
                % Change column headers
                set(app.NodeDataTable,'ColumnName',{'Radius of Comm.','X Position',...
                    'Y Position'});
                app.One.Value = false;
                app.Two.Value = true;
                % Hide trajectory plot
                set(app.NodeTrajectoryPlot,'Visible',false);
                % Increase location plot size
                set(app.NodeLocationPlot,'Position',[12 15 300 378]);
            end
        end
        
        function plotLocation(app)
            delta = 1/20;
            cla(app.NodeLocationPlot);
            % Plotting the node location on the Node Location Plot
            hold(app.NodeLocationPlot,'on');
            
            % Plot the location of each node
            scatter(app.NodeLocationPlot,nodeData(:,1),nodeData(:,2),'Marker','o');
            
            % Plot circles around each agent to reflect an agents radius of communication
            for node = 1:size(nodeData,1)
                radii = nodeData(node,3);
                pos = [nodeData(node,1)-radii nodeData(node,2)-radii 2*radii 2*radii];
                rectangle(app.NodeLocationPlot,'Position',pos,'Curvature',[1 1]);
            end
            
            % Adding node labels
            text(app.NodeLocationPlot, nodeData(:,1)+delta, nodeData(:,2)+delta,...
                cellfun(@num2str,num2cell((1:app.NodesEditField.Value)'),'UniformOutput',false));
            
            % Adjusting plotting window to display all nodes
            app.NodeLocationPlot.XLim = ([min(nodeData(:,1)) - 3, max(nodeData(:,1)) + 3]);
            app.NodeLocationPlot.YLim = ([min(nodeData(:,2)) - 3, max(nodeData(:,2)) + 3]);
            
            hold(app.NodeLocationPlot,'off')
        end
        
        function plotTrajectory(app)
            delta = 1/20;
            cla(app.NodeTrajectoryPlot);
            hold(app.NodeTrajectoryPlot,'on')
        
            for node = 1:app.NodesEditField.Value
                plot(app.NodeTrajectoryPlot, 0:timeStep:timeStep*(iteration-1), nodeDataHistory(1:iteration,(5*node)-4),...
                    '-o','MarkerIndices',iteration);
                text(app.NodeTrajectoryPlot, timeStep*(iteration-1)+delta, nodeDataHistory(iteration,5*node-4),sprintf('%d',node));
            end
           
            app.NodeTrajectoryPlot.XLim = ([0, app.DurationEditField.Value*timeStep]);
            app.NodeTrajectoryPlot.YLim = ([min(nodeDataHistory(:,1)) - 1, max(nodeDataHistory(:,1)) + 1]);
        
            hold(app.NodeTrajectoryPlot, 'off') 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Suppress warnings for spreadsheet writing
            warning('off','MATLAB:xlswrite:AddSheet');
            
            app.NodeDataTable.Data = zeros(5,4);
            app.NodesEditField.Value = 5;
        end

        % Button pushed function: StopButton
        function stopButtonPushed(app, event)
            app.isStopPushed = true;
        end

        % Value changed function: NodesEditField
        function numNodesChanged(app, event)
            % This function adjusts the number of rows in the agent
            % position and velocity information so it refelcts the new
            % number of agents to be in the simulation.
            
            % New total number of agents 
            newNumNodes = app.NodesEditField.Value; 
            
            % Old total number of agents
            oldNumNodes = size(app.NodeDataTable.Data,1);
            delta = newNumNodes - oldNumNodes;
            
            % If adding nodes
            if delta > 0
                app.NodeDataTable.Data = vertcat(app.NodeDataTable.Data, zeros(delta,5));
            else
                app.NodeDataTable.Data = app.NodeDataTable.Data(1:newNumNodes,:);
            end
        end

        % Button pushed function: StartButton
        function startSimulation(app, event)
        % Main simulation code
        %% TODO: Finish fixing the plotting
        %% TODO: Review external functions
        app.isStopPushed = false;
        
        % Iteration counter variable used in while loop
        iteration = 1;
        duration = app.DurationEditField.Value;
        % Reads in the timestep to be used in the simulation
        timeStep = app.TimeStepEditField.Value;
        isOneDim = strcmp(app.DimensionsButtonGroup.SelectedObject.Text,'1');
      
        % Storing the Node information for the current iteration in the simulation
        nodeData = app.NodeDataTable.Data;
        
        % Storing the node information from each iteration in the simulation to be exported 
        % in an excel spreadsheet upon simulation completion
        if isOneDim
            nodeDataHistory = zeros(app.DurationEditField.Value, 4*size(nodeData,1));
        else
            nodeDataHistory = zeros(app.DurationEditField.Value, 3*size(nodeData,1));
        end
        nodeDataHistory(1,:) = reshape(nodeData',1,[]);
        
        % While Loop that runs the simulation
        while iteration < duration && ~app.isStopPushed
            app.CurrentIterationEditField.Value = iteration;
            
            % STUDENT MADE FUNCTION to calculate the Adjacency Matrix using Node Data
            A = CalculateA(nodeData);
            % STUDENT MADE FUNCTION to calculate the Adjacency Matrix using Node Data
            L = calcL(A);
            
            % STUDENT MADE FUNCTION to update the Node Data. Must update
            % Node Positions, but can also update radii of communication
            % and left and right offsets with the introduction of cost
            % functions
            nodeData = UpdateNodeData(nodeData,L,timeStep,app.CurrentIterationEditField.Value);
           
            % Stroing node data for this iteration to node history
            nodeDataHistory(iteration,:) = reshape(nodeData',1,[]);
            
            %% Plotting %%
            app.NodeDataTable.Data = nodeData;
            plotLocation(app);
            % Only plot trajectory if simulation is one dim
            if isOneDim
                plotTrajectory(app);
            end
            %% END Plotting %%
            
            iteration = iteration + 1;
            pause(0.2);
        end
        % Update last iteration value
        app.CurrentIterationEditField.Value = iteration;
        
        % Save simulation Results
        saveSimulationData(app, nodeDataHistory, iteration);
        end

        % Button pushed function: LoadButton
        function loadNodeInfo(app, event)
            filename = app.FilenameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                file = load(filename);
                try
                    % Load dimension
                    isOneDimensional = file.isOneDimensional;
                    % Load data
                    app.NodeDataTable.Data = file.matrix;
                    % Set num agents edit field
                    app.NodesEditField.Value = size(app.NodeDataTable.Data,1);
                    % Set UI based on dimension
                    toggleDimensionUI(app, isOneDimensional);
                catch ME
                    disp(['Error: ' filename ' is corrupt. Please delete/fix and try again'])
                    disp(ME)
                    % Could use new blank data here, but that could
                    % overwrite any potentially recoverable data in the
                    % corrupt matrix
                    delete(app.Window);
                end
            else
                disp(['Error: No file with name: ' filename])
            end
        end

        % Selection changed function: DimensionsButtonGroup
        function dimensionChanged(app, event)
            isOneDim = strcmp(app.DimensionsButtonGroup.SelectedObject.Text,'1');
            selection = uiconfirm(app.Window,'This could delete some data. Continue?', 'Confirm Change',...
                    'Options',{'Yes', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',2);
            switch (selection)
                case 'Yes'
                    oldData = app.NodeDataTable.Data;
                    % If it was 2D and is now 1D
                    if isOneDim
                        % Keep rComm,xPos, add left/right noise columns
                        toggleDimensionUI(app, true);
                        app.NodeDataTable.Data = cat(2,oldData(:,1:2),zeros(size(oldData,1),2));
                    % If it was 1D and is now 2D
                    else
                        % Keep rComm,Pos, add y Pos
                        toggleDimensionUI(app, false);
                        app.NodeDataTable.Data = cat(2,oldData(:,1:2),zeros(size(oldData,1),1));
                    end
                case 'Cancel'
                    % Revert UI to previous state
                    toggleDimensionUI(app, ~isOneDim);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Opinion Algorithm';
            app.NodeDataTable = uitable(app.Window);
            app.NodeDataTable.ColumnName = {'Radius of Comm.'; 'Position'; 'Left Noise'; 'Right Noise'};
            app.NodeDataTable.ColumnEditable = [true true true true true];
            app.StartButton = uibutton(app.Window, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startSimulation, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Window, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.NumberofNodesLabel = uilabel(app.Window);
            app.NumberofNodesLabel.Text = 'Number of Nodes';
            app.NodesEditField = uieditfield(app.Window, 'numeric');
            app.NodesEditField.ValueChangedFcn = createCallbackFcn(app, @numNodesChanged, true);
            app.NodesEditField.Value = 5;
            app.DimensionsButtonGroup = uibuttongroup(app.Window);
            app.DimensionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @dimensionChanged, true);
            app.DimensionsButtonGroup.Title = 'Simulation Dimension(s)';
            app.One = uiradiobutton(app.DimensionsButtonGroup);
            app.One.Text = '1';
            app.One.Value = true;
            app.Two = uiradiobutton(app.DimensionsButtonGroup);
            app.Two.Text = '2';
            app.NodeTrajectoryPlot = uiaxes(app.Window);
            title(app.NodeTrajectoryPlot, 'Node Trajectory')
            xlabel(app.NodeTrajectoryPlot, 'Time')
            ylabel(app.NodeTrajectoryPlot, 'Location')
            app.SimulationDurationLabel = uilabel(app.Window);
            app.SimulationDurationLabel.Text = 'Simulation Duration';
            app.DurationEditField = uieditfield(app.Window, 'numeric');
            app.DurationEditField.Limits = [1 Inf];
            app.DurationEditField.Value = 20;
            app.CurrentIterationLabel = uilabel(app.Window);
            app.CurrentIterationLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.Window, 'numeric');
            app.CurrentIterationEditField.Limits = [0 Inf];
            app.CurrentIterationEditField.Editable = 'off';
            app.TimeStepEditFieldLabel = uilabel(app.Window);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.Window, 'numeric');
            app.TimeStepEditField.Value = 0.2;
            app.SimulationControlsLabel = uilabel(app.Window);
            app.SimulationControlsLabel.Text = 'Simulation Controls';
            app.LoadButton = uibutton(app.Window, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadNodeInfo, true);
            app.LoadButton.Text = 'Load';
            app.AgentInformationLabel = uilabel(app.Window);
            app.AgentInformationLabel.Text = 'Agent Information';
            app.FilenameEditFieldLabel = uilabel(app.Window);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.Window, 'text');
            app.FilenameEditField.Value = 'agentInfo.mat';
            app.NodeLocationPlot = uiaxes(app.Window);
            title(app.NodeLocationPlot, 'Node Location')
            xlabel(app.NodeLocationPlot, 'X')
            ylabel(app.NodeLocationPlot, 'Y')
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpinionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end